classdef MicSimA02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicFRSimulationUIFigure         matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        MIcTypeDropDownLabel            matlab.ui.control.Label
        MIcTypeDropDown                 matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        VentDiametermmEditFieldLabel    matlab.ui.control.Label
        VentDiametermmEditField         matlab.ui.control.NumericEditField
        VentLengthmmEditFieldLabel      matlab.ui.control.Label
        VentLengthmmEditField           matlab.ui.control.NumericEditField
        GasketDiametermmEditFieldLabel  matlab.ui.control.Label
        GasketDiametermmEditField       matlab.ui.control.NumericEditField
        GasketLengthmmEditFieldLabel    matlab.ui.control.Label
        GasketLengthmmEditField         matlab.ui.control.NumericEditField
        PCBThicknessmmEditFieldLabel    matlab.ui.control.Label
        PCBThicknessmmEditField         matlab.ui.control.NumericEditField
        PCBHoleDiametermmEditFieldLabel  matlab.ui.control.Label
        PCBHoleDiametermmEditField      matlab.ui.control.NumericEditField
        VentNumberEditFieldLabel        matlab.ui.control.Label
        VentNumberEditField             matlab.ui.control.NumericEditField
        Button_BP                       matlab.ui.control.Button
        Button_TP                       matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Button_RCLabLogo                matlab.ui.control.Button
        FrontFiringTopPortLabel_2       matlab.ui.control.Label
        SideFiringTopPortLabel_2        matlab.ui.control.Label
        FrontFiringBottomPortLabel      matlab.ui.control.Label
        SideFiringBottomPortLabel       matlab.ui.control.Label
        MicrophoneFrequencyResponseSimulationLabel  matlab.ui.control.Label
        MicSensitivity38dBVLabel        matlab.ui.control.Label
    end

    methods (Access = private)

        function Z=C_Volume(app,width,length,height)
            width=width/1000;% convert mm to m
            length=length/1000;% convert mm to m
            height=height/1000;% convert mm to m
            volume=width*length*height;
            Z=volume/1.21/340^2;
        end
    
        function Z=M_Volume(app,width,length,height)
            width=width/1000;% convert mm to m
            length=length/1000;% convert mm to m
            height=height/1000;% convert mm to m
            Z=1.21*length/width/height;
        end

        function Z=R_Tunnel_cir(app,diameter, length)
            a=diameter/2/1000;% convert mm to m and diameter to radius
            length=length/1000;% convert mm to m
            Z=8*1.86e-5*length/pi/a^4;
        end

        function Z=M_Tunnel_cir(app,diameter, length)
            a=diameter/2/1000;% convert mm to m and diameter to radius
            length=length/1000;% convert mm to m
            Z=4/3*1.21*length/pi/a^2;
        end
    
        function Z=C_Tunnel_cir(app,diameter, length)
            diameter=diameter/1000;% convert mm to m
            length=length/1000;% convert mm to m
            volume=diameter^2/4*3.14*length
            Z=volume/1.21/340^2;
        end
    
        function Ra=R_Radiation(app,no, d, w)
            a=d/2/1000;
            c=340;
            lo=1.21;
            k=w/c;
            Ra=1/2/pi*lo*c*k^2/no;
        end          

        function Ma=M_Radiation(app,no, d, w)
            a=d/2/1000;
            c=340;
            lo=1.21;
            k=w/c;
            Ma=8*lo/3/a/(pi)^2/no;
        end
    
        function T=T_TF(app,N,w)
            %Transformer factor N1:N2=1:N
            T1=[1/N 0;0 N];
            T=T1;
        end
    
        function T=T_P(app,Z)
            T1=[1 0;1/Z 1];
            T=T1;
        end
    
        function T=T_S(app,Z)
            T1=[1 Z;0 1];
            T=T1;
        end
    
        function T=T_Parallel(app,Zi)
          Z=Zi(1);
          for i=2:length(Zi)
              Z=1./(1./Z+1./Zi(i));
          end      
          T=[1 Z;0 1];
        end
  
        function Z1=Zacs(app,T,Z)
            Z1=[1 0]*T*[Z;1]/([0 1]*T*[Z;1]);
        end
        function f=frequency(app,fb,fe,oct)
            fbb=fb;
            f(1)=fb;
            count=1;
            for t=1:floor(log2(fe/fb))+1
                for n=1:oct
                    count=count+1;
                    f(count)=fb*10^(log10(2)/oct*n);
                    if f(count)>fe
                        break;
                    else
                    end
                end
                fb=f(end);
            end
            f=round(f);
        end
        function FR_Callback(app)
                   
            P=1; % Pressuer, Pa.
            Density_DP=2330; % Density of diaphragm
            Density_BP=2843; % Density of backplate
            Dia_DP=670e-6; % diaphragm diameter
            Dia_BP=560e-6; % backplate diameter
            Cp=1e-9; % Parasitic capacitor
            u=1.73e-5; % Air viscosity Ns/m^2
            Vb=8.6;
            t1=1e-6;
            t2=1.2e-6;
            Ad=pi*Dia_DP^2/4;
            n=6610/Ad;
            r=2e-6;
            afar=n*pi*r^2;
            Ab=pi*Dia_BP^2/4*(1-afar);
            pa=0;p=1;Cp=1e-12;
            sp_w_cmp = 1;
            sp_h_cmp = 0.2;
            sp_lc_cmp = 0.2;
            sp_n_cmp = 1;
            sp_d_cmp = 0.325;
            
            Air_D=1.21; % Air density;
            C=340; % Air velocity
            Bias=10; % Bias voltage,unit: V
            d_airgap=3.5e-6;
            % UI Inputs
            % Vent
            app.VentDiametermmEditField.Value
            sp_d=app.VentDiametermmEditField.Value
            sp_lc=app.VentLengthmmEditField.Value
            sp_n=app.VentNumberEditField.Value
            
            % Gasket
            tn1_d=app.GasketDiametermmEditField.Value
            tn1_lc=app.GasketLengthmmEditField.Value

            % PCB
            tn2_lc=app.PCBThicknessmmEditField.Value
            tn2_d=app.PCBHoleDiametermmEditField.Value

                
            Cmd=0.1378;
            Cmb=0.00057252;
            Cbc=2.0966e-12;
            Cfc=0.7221e-14;
            %Cfc=1.62e-14;
            Cp=1e-12;

            Mmd=Density_DP*Ad*t1;
            Mmb=Density_BP*Ab*t2;
            Cad=Cmd*Ad^2;
            Mad=Mmd/Ad^2;
            Cab=Cmb*Ab^2;
            Mab=Mmb/Ab^2;
            Rasp=R_Tunnel_cir(app,sp_d, sp_lc)/sp_n;
            Masp=M_Tunnel_cir(app,sp_d, sp_lc)/sp_n;
            Ratn1=R_Tunnel_cir(app,tn1_d, tn1_lc);
            Matn1=M_Tunnel_cir(app,tn1_d, tn1_lc);
            Catn1=C_Tunnel_cir(app,tn1_d, tn1_lc);
            Ratn2=R_Tunnel_cir(app,tn2_d, tn2_lc);
            Matn2=M_Tunnel_cir(app,tn2_d, tn2_lc);
            Catn2=C_Tunnel_cir(app,tn2_d, tn2_lc);
 
            
            count=0;
            fb=100;fe=20000;
            ff=frequency(app,fb,fe,12);
            for t=1:length(ff)
                count=count+1;
                f=ff(t);
                w=2*pi*f;
                C0=8.85e-12*Ab/d_airgap;
                fhi3=d_airgap*(Ad*Cmd+Ab*Cmb)/(Cmd+Cmb)/Vb/C0;
                Ce=C0+Cp;
                Ze=1/i/w/Ce/fhi3^2;
                Ra=12*u/n/pi/d_airgap^3/Ab*(afar/2-(afar^2)/8-log(afar)/4-3/8);
                Rh=8*u*t2/(pi*n*r^4);
                Mrad=M_Radiation(app,sp_n, sp_d, w);
                Rrad=R_Radiation(app,sp_n, sp_d, w);

                Z_ag=Ra+Rh+1/i/w/Ce;
                Z_ab=1/i/w/Cab+i*w*Mab;
                Z_ag_bp=1/(1/Z_ab+1/(Z_ag+Ze));
                T_rad=T_Parallel(app,[i*w*Mrad,Rrad]);

                T_sp=T_S(app,i*w*Masp/sp_n)*T_S(app,Rasp/sp_n);

                T_tn1=T_S(app,i*w*0.5*Matn1)*T_S(app,Ratn1*20)*T_P(app,1/i/w/Catn1)*T_S(app,i*w*0.5*Matn1);
                T_tn2=T_S(app,i*w*0.5*Matn2)*T_S(app,Ratn2*20)*T_P(app,1/i/w/Catn2)*T_S(app,i*w*0.5*Matn2);
                T_tn1*T_tn2
                T_ad=T_S(app,1/i/w/Cad)*T_S(app,i*w*Mad);
                T_bpag=T_Parallel(app,[Ra+Rh+1/i/w/Ce,1/i/w/Cab+i*w*Mab]);
                T_bgag=T_S(app,Z_ag_bp);
                Z_ab=1/i/w/Cab+i*w*Mab;
              
                T_bc=T_S(app,1/i/w/Cbc);
                T_fc=T_P(app,1/i/w/Cfc);
                if app.MIcTypeDropDown.Value == "Bottom Port"
                    Tn=T_rad*T_sp*T_tn1*T_tn2*T_fc*T_bpag*T_ad*T_bc;
                else
                    Tn=T_rad*T_sp*T_tn1*T_fc*T_bpag*T_ad*T_bc;
                end
           
                Zac=[1 0]*Tn*[0;1]/([0 1]*Tn*[0;1]);
                U=P/Zac;
                if app.MIcTypeDropDown.Value == "Bottom Port"
                    Tm=T_rad*T_sp*T_tn1*T_tn2*T_fc;
                else
                    Tm=T_rad*T_sp*T_tn1*T_fc;
                end
                PnUn=inv(Tm)*[P;U];

                Ubpag=PnUn(2)*Z_ab/(Z_ab+Z_ag);

                freq(count)=f;
                VdB(count)=20*log(abs(Ubpag*Ze));
                

            end
            app.MIcTypeDropDown.Value
            S_ref=find(freq==1008);
            VdB(S_ref)
            VdB = VdB + 3; % Compensate to -38dBV
            %VdB=VdB-VdB(S_ref);

            semilogx(app.UIAxes,freq,VdB,'linewidth',2);
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            hold(app.UIAxes, 'on');
            app.UIAxes.XLim = [100 1e4];
            app.UIAxes.YLim = [-50 10];
            legend(app.UIAxes,'Location','EastOutside')
            output=[freq;VdB];
            
            % Save simulation result to a temp output file
            if app.MIcTypeDropDown.Value == "Bottom Port"
                Variable_name = ["Parameter(s)","Vent diameter" "Vent length" "Vent number" "Boot diameter" "Boot length" "PCB Hole Diameter" "PCB Thickness"];
                Variable_value = ["Value(s)",sp_d, sp_lc , sp_n , tn1_d , tn1_lc, tn2_d , tn2_lc];
            else
                Variable_name = ["Parameter(s)","Vent diameter" "Vent length" "Vent number" "Boot diameter" "Boot length"];
                Variable_value = ["Value(s)",sp_d, sp_lc , sp_n , tn1_d , tn1_lc];
            end
            fpath = mfilename('fullpath')
            [fdir, fname] = fileparts(fpath)
            datadir = strcat(fdir,'\data\')
            %[status, msg, msgID] = mkdir('\Data\');
            if ~exist(datadir,'dir')
                mkdir(datadir)
            end
            xlsfname = 'mic_data.xlsx';
            xlsdatafile = strcat(datadir,xlsfname)
            if exist(xlsdatafile)
                [NUM,TXT,RAW]=xlsread(xlsdatafile,1);
                data_size = size(RAW);
                data_row = data_size(1);
            else
                fullfile(xlsdatafile)
                data_row = 0
            end
            data_row = data_row + 1;
            cell = strcat('A',num2str(data_row));
            xlswrite(xlsdatafile,{'Parameters'},1,cell);
            cell = strcat('B',num2str(data_row));
            xlswrite(xlsdatafile,Variable_name,1,cell);
            data_row = data_row + 1;
            cell = strcat('A',num2str(data_row));
            xlswrite(xlsdatafile,{'Value'},1,cell);
            cell = strcat('B',num2str(data_row));
            xlswrite(xlsdatafile,Variable_value,1,cell);
            data_row = data_row + 1;
            cell = strcat('A',num2str(data_row));
            xlswrite(xlsdatafile,{'Frequency'},1,cell);
            cell = strcat('B',num2str(data_row));
            xlswrite(xlsdatafile,freq,1,cell);
            data_row = data_row + 1;
            cell = strcat('A',num2str(data_row));
            xlswrite(xlsdatafile,{'Mic_Output'},1,cell);
            cell = strcat('B',num2str(data_row));
            xlswrite(xlsdatafile,VdB,1,cell);
            fclose('all');
        end

    end

    methods (Access = private)

        % Value changed function: VentDiametermmEditField
        function VentDia(app, event)
            value = app.VentDiametermmEditField.Value;
            
        end

        % Value changed function: VentLengthmmEditField
        function VentLen(app, event)
            value = app.VentLengthmmEditField.Value;
            
        end

        % Value changed function: GasketDiametermmEditField
        function GasketDia(app, event)
            value = app.GasketDiametermmEditField.Value;
            
        end

        % Value changed function: GasketLengthmmEditField
        function GasketLen(app, event)
            value = app.GasketLengthmmEditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FR_Callback
        end

        % Value changed function: VentNumberEditField
        function VentNum(app, event)
            value = app.VentNumberEditField.Value;
            
        end

        % Value changed function: PCBThicknessmmEditField
        function PCBThi(app, event)
            value = app.PCBThicknessmmEditField.Value;
            
        end

        % Value changed function: PCBHoleDiametermmEditField
        function PCBDia(app, event)
            value = app.PCBHoleDiametermmEditField.Value;
            value1 = app.MIcTypeDropDown.Value;
            if strcmp(value1,'Top Port')
                app.PCBThicknessmmEditField.Editable = 'off';
                app.PCBHoleDiametermmEditField.Editable = 'off';
            end
        end

        % Value changed function: MIcTypeDropDown
        function MicType(app, event)
            value = app.MIcTypeDropDown.Value;
            if strcmp(value,'Top Port')
                app.PCBThicknessmmEditField.Editable = 'off';
                app.PCBHoleDiametermmEditField.Editable = 'off';
            else
                app.PCBThicknessmmEditField.Editable = 'on';
                app.PCBHoleDiametermmEditField.Editable = 'on';
            end
        end

        % Button pushed function: ResetButton
        function ResetPlot(app, event)
            cla(app.UIAxes)
            xlsdatafile = 'C:\mic_data.xlsx';
            if isfile(xlsdatafile)
                delete 'C:\mic_data.xlsx'
            end
        end

        % Button pushed function: SaveButton
        function SaveFile(app, event)
            xlsdatafile = 'C:\mic_data.xlsx';
            if exist(xlsdatafile)
                [file,path,indx] = uiputfile('Mic_FR.xlsx');
                if file ~=0
                    copyfile(xlsdatafile,strcat(path,file))
                end
                
            else
                f = warndlg('No simulation data was found, please run first','warning')
            end
            
        end

        % Callback function
        function Button_TPPushed(app, event)
            
        end

        % Callback function
        function Button_BPPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicFRSimulationUIFigure = uifigure;
            app.MicFRSimulationUIFigure.Name = 'Mic FR Simulation';
            app.UIAxes = uiaxes(app.MicFRSimulationUIFigure);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'Hz')
            ylabel(app.UIAxes, 'dBV')
            app.MIcTypeDropDownLabel = uilabel(app.MicFRSimulationUIFigure);
            app.MIcTypeDropDownLabel.Text = 'MIc Type';
            app.MIcTypeDropDown = uidropdown(app.MicFRSimulationUIFigure);
            app.MIcTypeDropDown.Items = {'Top Port', 'Bottom Port'};
            app.MIcTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MicType, true);
            app.MIcTypeDropDown.Value = 'Top Port';
            app.Panel = uipanel(app.MicFRSimulationUIFigure);
            app.Panel.Title = 'Panel';
            app.VentDiametermmEditFieldLabel = uilabel(app.Panel);
            app.VentDiametermmEditFieldLabel.Text = 'Vent Diameter (mm)';
            app.VentDiametermmEditField = uieditfield(app.Panel, 'numeric');
            app.VentDiametermmEditField.Limits = [0.1 Inf];
            app.VentDiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @VentDia, true);
            app.VentDiametermmEditField.Value = 0.6;
            app.VentLengthmmEditFieldLabel = uilabel(app.Panel);
            app.VentLengthmmEditFieldLabel.Text = 'Vent Length (mm)';
            app.VentLengthmmEditField = uieditfield(app.Panel, 'numeric');
            app.VentLengthmmEditField.Limits = [0.1 Inf];
            app.VentLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @VentLen, true);
            app.VentLengthmmEditField.Value = 0.5;
            app.GasketDiametermmEditFieldLabel = uilabel(app.Panel);
            app.GasketDiametermmEditFieldLabel.Text = 'Gasket Diameter (mm)';
            app.GasketDiametermmEditField = uieditfield(app.Panel, 'numeric');
            app.GasketDiametermmEditField.Limits = [0.1 Inf];
            app.GasketDiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @GasketDia, true);
            app.GasketDiametermmEditField.Value = 0.8;
            app.GasketLengthmmEditFieldLabel = uilabel(app.Panel);
            app.GasketLengthmmEditFieldLabel.Text = 'Gasket Length (mm)';
            app.GasketLengthmmEditField = uieditfield(app.Panel, 'numeric');
            app.GasketLengthmmEditField.Limits = [0.1 Inf];
            app.GasketLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @GasketLen, true);
            app.GasketLengthmmEditField.Value = 0.5;
            app.PCBThicknessmmEditFieldLabel = uilabel(app.Panel);
            app.PCBThicknessmmEditFieldLabel.Text = 'PCB Thickness (mm)';
            app.PCBThicknessmmEditField = uieditfield(app.Panel, 'numeric');
            app.PCBThicknessmmEditField.Limits = [0.1 Inf];
            app.PCBThicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @PCBThi, true);
            app.PCBThicknessmmEditField.Editable = 'off';
            app.PCBThicknessmmEditField.Value = 0.3;
            app.PCBHoleDiametermmEditFieldLabel = uilabel(app.Panel);
            app.PCBHoleDiametermmEditFieldLabel.Text = 'PCB Hole Diameter (mm)';
            app.PCBHoleDiametermmEditField = uieditfield(app.Panel, 'numeric');
            app.PCBHoleDiametermmEditField.Limits = [0.2 Inf];
            app.PCBHoleDiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @PCBDia, true);
            app.PCBHoleDiametermmEditField.Editable = 'off';
            app.PCBHoleDiametermmEditField.Value = 0.35;
            app.VentNumberEditFieldLabel = uilabel(app.Panel);
            app.VentNumberEditFieldLabel.Text = 'Vent Number';
            app.VentNumberEditField = uieditfield(app.Panel, 'numeric');
            app.VentNumberEditField.Limits = [1 Inf];
            app.VentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @VentNum, true);
            app.VentNumberEditField.Value = 1;
            app.Button_BP = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.Button_BP.Icon = 'BP_2.png';
            app.Button_BP.Text = '';
            app.Button_TP = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.Button_TP.Icon = 'TP_2.png';
            app.Button_TP.Text = '';
            app.RunButton = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveButton = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFile, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlot, true);
            app.ResetButton.Text = 'Reset';
            app.Button_RCLabLogo = uibutton(app.MicFRSimulationUIFigure, 'push');
            app.Button_RCLabLogo.Icon = 'RCLab Logo.png';
            app.Button_RCLabLogo.Text = '';
            app.FrontFiringTopPortLabel_2 = uilabel(app.MicFRSimulationUIFigure);
            app.FrontFiringTopPortLabel_2.Text = 'Front Firing - Top Port';
            app.SideFiringTopPortLabel_2 = uilabel(app.MicFRSimulationUIFigure);
            app.SideFiringTopPortLabel_2.Text = 'Side Firing - Top Port';
            app.FrontFiringBottomPortLabel = uilabel(app.MicFRSimulationUIFigure);
            app.FrontFiringBottomPortLabel.Text = 'Front Firing - Bottom Port';
            app.SideFiringBottomPortLabel = uilabel(app.MicFRSimulationUIFigure);
            app.SideFiringBottomPortLabel.Text = 'Side Firing - Bottom Port';
            app.MicrophoneFrequencyResponseSimulationLabel = uilabel(app.MicFRSimulationUIFigure);
            app.MicrophoneFrequencyResponseSimulationLabel.Text = 'Microphone Frequency Response Simulation';
            app.MicSensitivity38dBVLabel = uilabel(app.MicFRSimulationUIFigure);
            app.MicSensitivity38dBVLabel.Text = 'Mic Sensitivity: -38 dBV ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MicSimA02

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicFRSimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicFRSimulationUIFigure)
        end
    end
end